classdef SubmarineGUI_bu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SteamtemperatureEditFieldLabel  matlab.ui.control.Label
        SteamTEditField                 matlab.ui.control.NumericEditField
        PowerGaugeLabel                 matlab.ui.control.Label
        PowerGauge                      matlab.ui.control.Gauge
        PumpspeedLabel                  matlab.ui.control.Label
        PumpspeedSpinner                matlab.ui.control.Spinner
        FuelconsumptionlhLabel          matlab.ui.control.Label
        FuelFlowGauge                   matlab.ui.control.LinearGauge
        CombustiontemperatureEditFieldLabel  matlab.ui.control.Label
        CombustionTEditField            matlab.ui.control.NumericEditField
        CombustionLamp                  matlab.ui.control.Lamp
        SteammassflowkghGaugeLabel      matlab.ui.control.Label
        SteammassflowGauge              matlab.ui.control.LinearGauge
        SteamTLamp                      matlab.ui.control.Lamp
        UIAxes                          matlab.ui.control.UIAxes
        NlowLabel                       matlab.ui.control.Label
        NlowSpinner                     matlab.ui.control.Spinner
        NhighLabel                      matlab.ui.control.Label
        NhighSpinner                    matlab.ui.control.Spinner
        CompressorloadLabel_2           matlab.ui.control.Label
        CompressorLowSpinner            matlab.ui.control.Spinner
        CompressorloadLabel_3           matlab.ui.control.Label
        CompressorHighSpinner           matlab.ui.control.Spinner
        CompressorloadLabel             matlab.ui.control.Label
        CompressorloadGauge             matlab.ui.control.Gauge
        EvaporatorfluegasLabel          matlab.ui.control.Label
        EvapFrSpinner                   matlab.ui.control.Spinner
        VaporfractionLabel              matlab.ui.control.Label
        x2Gauge                         matlab.ui.control.NinetyDegreeGauge
        SaturatedLamp                   matlab.ui.control.Lamp
        DepthmSpinnerLabel              matlab.ui.control.Label
        DepthSpinner                    matlab.ui.control.Spinner
        SuperheatererosionLampLabel     matlab.ui.control.Label
        SuperonLamp                     matlab.ui.control.Lamp
        EvaporatorerosionLampLabel      matlab.ui.control.Label
        EvaperoLamp                     matlab.ui.control.Lamp
        MalfunctionLabel                matlab.ui.control.Label
        DeadLamp                        matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        MemoryTimer        % Timer object
        PlotLine           % Line object
        sub                % Submarine struct
        
        %turbine parameters
        TurbID = 16 %selected turbine
        
        %heat exchanger parameters
        PipeID = 1; %selected heat exchanger pipe
        PipeL = 4.77; %m, heat exchanger pipe length
        EvapPipeNR = 89;  % number of tubes in evaporator
        SuperPipeNr = 30; % number of tubes in superheater section
    
        %compressor parameters
        ComprID = 16; % selected compressor
    
        %fuel pump parameters
        FuelPumpID = 9; %selected fuel pump

        Pspeed
        depth

    end
    methods (Access = private)
    
       function MemoryTimerFcn(app,~,~)
           app.Pspeed = app.PumpspeedSpinner.Value;
           app.sub.Compr.N1 = app.NlowSpinner.Value;
           app.sub.Compr.N2 = app.NhighSpinner.Value;
           if app.sub.Compr.N2 < app.sub.Compr.N1+0.01
               app.NhighSpinner.Value = app.sub.Compr.N1+0.01;
           end
%           app.sub.Compr.N2 = max(app.sub.Compr.N2, app.sub.Compr.N1+0.01);
 %          app.NhighSpinner.Value = app.sub.Compr.N2;
           app.sub.Compr.L1 = app.CompressorLowSpinner.Value/100;
           app.sub.Compr.L2 = app.CompressorHighSpinner.Value/100;
           
           app.depth = app.DepthSpinner.Value;
           %app.depth = min(app.depth, app.hSubMax-1);
           %app.depth = min(app.depth, app.sub.Env.maxDepth);
           %app.DepthSpinner.Value = app.depth;
           
           app.sub.FG.HE.Evap.FGfraction = app.EvapFrSpinner.Value/100; % fraction of total flue gas to evaporator

           [app.sub] = SubmarineStepCalc(app.sub, app.Pspeed, app.depth);
           if app.Pspeed > app.sub.Fuel.Pump.Nallowed
                app.PumpspeedSpinner.Value = app.sub.Fuel.Pump.Nallowed;
           end
           
           app.FuelFlowGauge.Value = app.sub.Fuel.Pump.Q; %l/h
           app.CompressorloadGauge.Value = app.sub.Compr.L*100;
           app.CombustionTEditField.Value = app.sub.FG.Comb.Tout;
            if app.sub.FG.Comb.Tout > 800 % ovo popraviti!!!!!!!!!!!!!!!!!!!!!!!
                app.CombustionLamp.Color = 'red';
            else
                app.CombustionLamp.Color = 'green';
            end
            if app.sub.HE.Evap.ero 
                app.EvaperoLamp.Color = 'red';
            else
                app.EvaperoLamp.Color = 'green';
            end
            if app.sub.HE.Super.ero 
                app.SuperonLamp.Color = 'red';
            else
                app.SuperonLamp.Color = 'green';
            end               
            if app.sub.Turb.Power < app.sub.Env.Pmin
                app.DeadLamp.Color = 'red';
            else
                app.DeadLamp.Color = 'green';
            end
            
            %evaporator
            app.SteammassflowGauge.Value = app.sub.Steam.qmSteam*3600;
            
            %superheater
            app.SteamTEditField.Value = app.sub.Steam.HE.Super.Tout;
            if app.sub.Steam.HE.Super.Tout > app.sub.Turb.Tmax
                app.SteamTLamp.Color = 'red';
            else
                app.SteamTLamp.Color = 'green';
            end

            app.PowerGauge.Value = app.sub.Turb.Power;
            app.x2Gauge.Value = app.sub.Steam.Turb.xOut*100;
            if app.sub.Steam.Turb.xOut < 0.9
                app.SaturatedLamp.Color = 'red';
            else
                app.SaturatedLamp.Color = 'green';
            end

            y = app.sub.Turb.Power/(app.sub.FG.fuelHeatVal * app.sub.Fuel.Pump.qm / app.sub.FG.M_ethanol)*100;
            ydata = app.PlotLine.YData;
            ydata = circshift(ydata,1);
            ydata(1) = y;
            app.PlotLine.YData = ydata;

       end
    end
        

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
            load('GUIpar.mat', 'GUIparams')
            app.sub = createSub(GUIparams(1), GUIparams(2), GUIparams(3), GUIparams(4), GUIparams(5), GUIparams(6));

            app.PumpspeedSpinner.Limits=[app.sub.Fuel.Pump.Nmin,1];
            app.UIAxes.XLim = [0 60];
            app.UIAxes.XDir = 'reverse';
            app.UIAxes.YLim = [0 25];
            app.PowerGauge.Limits= [0,(app.sub.Turb.Pnom*1.5)];
            app.FuelFlowGauge.Limits = [0, app.sub.Fuel.Pump.qvMax*1.1];
            maxFlow = floor(app.sub.Turb.qmNom*1.1*3600/10)*10;
            app.SteammassflowGauge.Limits = [0, maxFlow];
            app.SteammassflowGauge.MajorTicks = [0, round(maxFlow/20)*10, maxFlow];           
            app.DepthSpinner.Limits = [5, app.sub.Env.maxDepth];
         
            app.NlowSpinner.Limits = [app.sub.Fuel.Pump.Nmin, 0.99];
            app.NhighSpinner.Limits = [app.sub.Fuel.Pump.Nmin+0.01, 1];

            % Initial plot is all zeros
            app.PlotLine = plot(app.UIAxes,0:60,zeros(1,61));
            
            app.MemoryTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 1, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.MemoryTimerFcn);    % Specify callback function
            start(app.MemoryTimer);

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SteamtemperatureEditFieldLabel = uilabel(app.UIFigure);
            app.SteamtemperatureEditFieldLabel.Text = 'Steam temperature';
            app.SteamTEditField = uieditfield(app.UIFigure, 'numeric');
            app.SteamTEditField.Editable = 'off';
            app.PowerGaugeLabel = uilabel(app.UIFigure);
            app.PowerGaugeLabel.Text = 'Power';
            app.PowerGauge = uigauge(app.UIFigure, 'circular');
            app.PowerGauge.Limits = [0 60];
            app.PumpspeedLabel = uilabel(app.UIFigure);
            app.PumpspeedLabel.Text = 'Pump speed [-]';
            app.PumpspeedSpinner = uispinner(app.UIFigure);
            app.PumpspeedSpinner.Step = 0.01;
            app.PumpspeedSpinner.Limits = [0.35 1];
            app.PumpspeedSpinner.Value = 0.84;
            app.FuelconsumptionlhLabel = uilabel(app.UIFigure);
            app.FuelconsumptionlhLabel.Text = 'Fuel consumption [l/h]';
            app.FuelFlowGauge = uigauge(app.UIFigure, 'linear');
            app.FuelFlowGauge.Limits = [0 50];
            app.CombustiontemperatureEditFieldLabel = uilabel(app.UIFigure);
            app.CombustiontemperatureEditFieldLabel.Text = 'Combustion temperature';
            app.CombustionTEditField = uieditfield(app.UIFigure, 'numeric');
            app.CombustionTEditField.Editable = 'off';
            app.CombustionLamp = uilamp(app.UIFigure);
            app.SteammassflowkghGaugeLabel = uilabel(app.UIFigure);
            app.SteammassflowkghGaugeLabel.Text = 'Steam mass flow [kg/h]';
            app.SteammassflowGauge = uigauge(app.UIFigure, 'linear');
            app.SteammassflowGauge.Limits = [100 500];
            app.SteamTLamp = uilamp(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'System efficiency %')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.NlowLabel = uilabel(app.UIFigure);
            app.NlowLabel.Text = 'Nlow [-]';
            app.NlowSpinner = uispinner(app.UIFigure);
            app.NlowSpinner.Step = 0.01;
            app.NlowSpinner.Limits = [0 0.99];
            app.NlowSpinner.Value = 0.84;
            app.NhighLabel = uilabel(app.UIFigure);
            app.NhighLabel.Text = 'Nhigh [-]';
            app.NhighSpinner = uispinner(app.UIFigure);
            app.NhighSpinner.Step = 0.01;
            app.NhighSpinner.Limits = [0.1 1];
            app.NhighSpinner.Value = 1;
            app.CompressorloadLabel_2 = uilabel(app.UIFigure);
            app.CompressorloadLabel_2.Text = 'Compressor load [%]';
            app.CompressorLowSpinner = uispinner(app.UIFigure);
            app.CompressorLowSpinner.Step = 0.1;
            app.CompressorLowSpinner.Limits = [0 100];
            app.CompressorLowSpinner.Value = 55.8;
            app.CompressorloadLabel_3 = uilabel(app.UIFigure);
            app.CompressorloadLabel_3.Text = 'Compressor load [%]';
            app.CompressorHighSpinner = uispinner(app.UIFigure);
            app.CompressorHighSpinner.Step = 0.1;
            app.CompressorHighSpinner.Limits = [0 100];
            app.CompressorHighSpinner.Value = 100;
            app.CompressorloadLabel = uilabel(app.UIFigure);
            app.CompressorloadLabel.Text = 'Compressor load %';
            app.CompressorloadGauge = uigauge(app.UIFigure, 'circular');
            app.EvaporatorfluegasLabel = uilabel(app.UIFigure);
            app.EvaporatorfluegasLabel.Text = 'Evaporator flue gas [%]';
            app.EvapFrSpinner = uispinner(app.UIFigure);
            app.EvapFrSpinner.Step = 0.1;
            app.EvapFrSpinner.Limits = [0.1 99];
            app.EvapFrSpinner.Value = 93.9;
            app.VaporfractionLabel = uilabel(app.UIFigure);
            app.VaporfractionLabel.Text = 'Vapor fraction';
            app.x2Gauge = uigauge(app.UIFigure, 'ninetydegree');
            app.x2Gauge.Limits = [85 100];
            app.SaturatedLamp = uilamp(app.UIFigure);
            app.DepthmSpinnerLabel = uilabel(app.UIFigure);
            app.DepthmSpinnerLabel.Text = 'Depth [m]';
            app.DepthSpinner = uispinner(app.UIFigure);
            app.DepthSpinner.Step = 0.1;
            app.DepthSpinner.Limits = [5 150];
            app.DepthSpinner.Value = 50;
            app.SuperheatererosionLampLabel = uilabel(app.UIFigure);
            app.SuperheatererosionLampLabel.Text = 'Superheater erosion';
            app.SuperonLamp = uilamp(app.UIFigure);
            app.EvaporatorerosionLampLabel = uilabel(app.UIFigure);
            app.EvaporatorerosionLampLabel.Text = 'Evaporator erosion';
            app.EvaperoLamp = uilamp(app.UIFigure);
            app.MalfunctionLabel = uilabel(app.UIFigure);
            app.MalfunctionLabel.Text = 'Malfunction';
            app.DeadLamp = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = SubmarineGUI_bu

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end