classdef SubmarineGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SteamtemperatureEditFieldLabel  matlab.ui.control.Label
        SteamTEditField                 matlab.ui.control.NumericEditField
        PowerkWGaugeLabel               matlab.ui.control.Label
        PowerkWGauge                    matlab.ui.control.Gauge
        PumpspeedLabel                  matlab.ui.control.Label
        PumpspeedSpinner                matlab.ui.control.Spinner
        CombustiontemperatureEditFieldLabel  matlab.ui.control.Label
        CombustionTEditField            matlab.ui.control.NumericEditField
        CombustionLamp                  matlab.ui.control.Lamp
        SteammassflowkghGaugeLabel      matlab.ui.control.Label
        SteammassflowGauge              matlab.ui.control.LinearGauge
        SteamTLamp                      matlab.ui.control.Lamp
        UIAxes                          matlab.ui.control.UIAxes
        NlowLabel                       matlab.ui.control.Label
        NlowSpinner                     matlab.ui.control.Spinner
        NhighLabel                      matlab.ui.control.Label
        NhighSpinner                    matlab.ui.control.Spinner
        CompressorloadLabel_2           matlab.ui.control.Label
        CompressorLowSpinner            matlab.ui.control.Spinner
        CompressorloadLabel_3           matlab.ui.control.Label
        CompressorHighSpinner           matlab.ui.control.Spinner
        CompressorloadLabel             matlab.ui.control.Label
        CompressorloadGauge             matlab.ui.control.Gauge
        EvaporatorfluegasLabel          matlab.ui.control.Label
        EvapFrSpinner                   matlab.ui.control.Spinner
        VaporfractionLabel              matlab.ui.control.Label
        x2Gauge                         matlab.ui.control.NinetyDegreeGauge
        SaturatedLamp                   matlab.ui.control.Lamp
        DepthmSpinnerLabel              matlab.ui.control.Label
        DepthSpinner                    matlab.ui.control.Spinner
        SuperheatererosionLampLabel     matlab.ui.control.Label
        SuperonLamp                     matlab.ui.control.Lamp
        EvaporatorerosionLampLabel      matlab.ui.control.Label
        EvaperoLamp                     matlab.ui.control.Lamp
        MalfunctionLabel                matlab.ui.control.Label
        DeadLamp                        matlab.ui.control.Lamp
        FuelconsumptionlhEditFieldLabel  matlab.ui.control.Label
        FuelconsumEditField             matlab.ui.control.NumericEditField
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        StartButton                     matlab.ui.control.StateButton
        ForwardButton                   matlab.ui.control.StateButton
        BalastpumpreferenceSliderLabel  matlab.ui.control.Label
        BalastpumpreferenceSlider       matlab.ui.control.Slider
        AngledegSpinnerLabel            matlab.ui.control.Label
        AngledegSpinner                 matlab.ui.control.Spinner
        BalastpumpGaugeLabel            matlab.ui.control.Label
        BalastpumpGauge                 matlab.ui.control.LinearGauge
        StopButton                      matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        MemoryTimer        % Timer object
        PlotLine           % Line object
        TrajectoryPlotLine % Line object
        VxPlotLine
        VyPlotLine
        sub                % Submarine struct

        Pspeed
        depth
    end
    methods (Access = private)
        
       function MemoryTimerFcn(app,~,~)
           global globalTime energy_x energy_y balast_fill distance_x distance_y
           
           if(app.StartButton.Value)                
                app.StartButton.Text = "Pause";
                app.StartButton.FontColor = [0.95,0.55,0.07];
                    
                app.Pspeed = app.PumpspeedSpinner.Value;
               app.sub.Compr.N1 = app.NlowSpinner.Value;
               app.sub.Compr.N2 = app.NhighSpinner.Value;
               if app.sub.Compr.N2 < app.sub.Compr.N1+0.01
                   app.NhighSpinner.Value = app.sub.Compr.N1+0.01;
               end
    %           app.sub.Compr.N2 = max(app.sub.Compr.N2, app.sub.Compr.N1+0.01);
     %          app.NhighSpinner.Value = app.sub.Compr.N2;
               app.sub.Compr.L1 = app.CompressorLowSpinner.Value/100;
               app.sub.Compr.L2 = app.CompressorHighSpinner.Value/100;
               
               %app.depth = app.DepthSpinner.Value;
               %app.depth = min(app.depth, app.hSubMax-1);
               %app.depth = min(app.depth, app.sub.Env.maxDepth);
               %app.DepthSpinner.Value = app.depth;
               
               app.sub.FG.HE.Evap.FGfraction = app.EvapFrSpinner.Value/100; % fraction of total flue gas to evaporator
               if(~app.ForwardButton.Value)
                   direction = 1; % Go forward
                   app.ForwardButton.Text = 'Backward';
               else
                   direction = -1; % Go backward
                   app.ForwardButton.Text = 'Forward';
               end
               
               if(~app.StopButton.Value)
                   motorStop = 1;
                   app.StopButton.Text = 'Stop';
               else
                   motorStop = 0;
                   app.StopButton.Text = 'Start';
               end
               
               [app.sub] = SubmarineStepCalc(app.sub, app.Pspeed, app.depth, app.AngledegSpinner.Value, direction,...
                   app.BalastpumpreferenceSlider.Value/100, motorStop);
               if app.Pspeed > app.sub.Fuel.Pump.Nallowed
                    app.PumpspeedSpinner.Value = app.sub.Fuel.Pump.Nallowed;
               end
               
               %app.FuelFlowGauge.Value = app.sub.Fuel.Pump.Q; %l/h
               app.FuelconsumEditField.Value = app.sub.Fuel.Pump.Q;
               app.CompressorloadGauge.Value = app.sub.Compr.L*100;
               app.CombustionTEditField.Value = app.sub.FG.Comb.Tout;
                if app.sub.FG.Comb.Tout > 850 % ovo popraviti!!!!!!!!!!!!!!!!!!!!!!!
                    app.CombustionLamp.Color = 'red';
                else
                    app.CombustionLamp.Color = 'green';
                end
                if app.sub.HE.Evap.ero 
                    app.EvaperoLamp.Color = 'red';
                else
                    app.EvaperoLamp.Color = 'green';
                end
                if app.sub.HE.Super.ero 
                    app.SuperonLamp.Color = 'red';
                else
                    app.SuperonLamp.Color = 'green';
                end               
                if app.sub.Turb.Power < app.sub.Env.Pmin
                    app.DeadLamp.Color = 'red';
                else
                    app.DeadLamp.Color = 'green';
                end
                
                %evaporator
                app.SteammassflowGauge.Value = app.sub.Steam.qmSteam*3600;
                
                %superheater
                app.SteamTEditField.Value = app.sub.Steam.HE.Super.Tout;
                if app.sub.Steam.HE.Super.Tout > app.sub.Turb.Tmax
                    app.SteamTLamp.Color = 'red';
                else
                    app.SteamTLamp.Color = 'green';
                end
    
                app.PowerkWGauge.Value = app.sub.Turb.Power;
                app.x2Gauge.Value = app.sub.Steam.Turb.xOut*100;
                app.BalastpumpGauge.Value = app.sub.balastP*100;
                
                app.depth = max(-app.sub.y, 5);
                app.DepthSpinner.Value = app.depth;
                
                if app.sub.Steam.Turb.xOut < 0.9
                    app.SaturatedLamp.Color = 'red';
                else
                    app.SaturatedLamp.Color = 'green';
                end
    
                y = app.sub.Turb.Power/(app.sub.FG.fuelHeatVal * app.sub.Fuel.Pump.qm / app.sub.FG.M_ethanol)*100;
                ydata = app.PlotLine.YData;
                ydata = circshift(ydata,1);
                ydata(1) = y;
                app.PlotLine.YData = ydata;
                
                % Vx and Vy plot
                t = globalTime;
                
                vxTimeData = app.VxPlotLine.XData;
                vxTimeData = circshift(vxTimeData,1);
                vxTimeData(1) = t;
                app.VxPlotLine.XData = vxTimeData;
                
                vx = app.sub.vx; %Zamijeniti vx
                vxData = app.VxPlotLine.YData;
                vxData = circshift(vxData,1);
                vxData(1) = vx;
                app.VxPlotLine.YData = vxData;
                
                vyTimeData = app.VyPlotLine.XData;
                vyTimeData = circshift(vyTimeData,1);
                vyTimeData(1) = t;
                app.VyPlotLine.XData = vyTimeData;
                            
                vy = app.sub.vy; %Zamijeniti vy
                vyData = app.VyPlotLine.YData;
                vyData = circshift(vyData,1);
                vyData(1) = vy;
                app.VyPlotLine.YData = vyData;
                
                % Elevation plot
                xElevation = app.sub.x; %Zamijeniti x
                xDataElevation = app.TrajectoryPlotLine.XData;
                xDataElevation = circshift(xDataElevation,1);
                xDataElevation(1) = xElevation;
                app.TrajectoryPlotLine.XData = xDataElevation;
                            
                yElevation = app.sub.y; %Zamijeniti y
                yDataElevation = app.TrajectoryPlotLine.YData;
                yDataElevation = circshift(yDataElevation,1);
                yDataElevation(1) = yElevation;
                app.TrajectoryPlotLine.YData = yDataElevation;  
                
                %Inclrease global time
                globalTime = globalTime + 1;
           else
                app.StartButton.Text = "Start";
                app.StartButton.FontColor = [0.3922 0.8314 0.0745];
           end
       end
    end
        

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %Declare global time
            global globalTime energy_x energy_y balast_fill distance_x distance_y
            globalTime = 0;
            energy_x = 0;
            energy_y = 0;
            distance_x = 0;
            distance_y = -5.001;
            balast_fill = 30/2;
            
            app.depth = 5.001;
            
            load('GUIpar.mat', 'GUIparams')
            app.sub = createSub(GUIparams(1), GUIparams(2), GUIparams(3), GUIparams(4), GUIparams(5), GUIparams(6));

            app.PumpspeedSpinner.Limits=[app.sub.Fuel.Pump.Nmin,1];
            app.UIAxes.XLim = [0 60];
            app.UIAxes.XDir = 'reverse';
            app.UIAxes.YLim = [0 25];
            app.UIAxes2.YLim = [-150 10];
            app.UIAxes3.YLim = [-6 6];
            app.UIAxes4.YLim = [-6 6];
            app.PowerkWGauge.Limits= [0,(app.sub.Turb.Pnom*1.5)];
            %app.FuelFlowGauge.Limits = [0, app.sub.Fuel.Pump.qvMax*1.1];
            maxFlow = floor(app.sub.Turb.qmNom*1.5*3600/10)*10;
            app.SteammassflowGauge.Limits = [0, maxFlow];
            app.SteammassflowGauge.MajorTicks = [0, round(maxFlow/20)*10, maxFlow];           
            app.DepthSpinner.Limits = [5, app.sub.Env.maxDepth];
         
            app.NlowSpinner.Limits = [app.sub.Fuel.Pump.Nmin, 0.99];
            app.NhighSpinner.Limits = [app.sub.Fuel.Pump.Nmin+0.01, 1];

            % Initial plot is all zeros
            app.PlotLine = plot(app.UIAxes,0:60,zeros(1,61));
            app.TrajectoryPlotLine = plot(app.UIAxes2,0:150,-200*ones(1,151));
            app.VxPlotLine = plot(app.UIAxes3,0:150,-10*ones(1,151));
            app.VyPlotLine = plot(app.UIAxes4,0:150,-10*ones(1,151));
            
            app.MemoryTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 1, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.MemoryTimerFcn);    % Specify callback function
            start(app.MemoryTimer);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SteamtemperatureEditFieldLabel = uilabel(app.UIFigure);
            app.SteamtemperatureEditFieldLabel.Text = 'Steam temperature';
            app.SteamTEditField = uieditfield(app.UIFigure, 'numeric');
            app.SteamTEditField.Editable = 'off';
            app.PowerkWGaugeLabel = uilabel(app.UIFigure);
            app.PowerkWGaugeLabel.Text = 'Power [kW]';
            app.PowerkWGauge = uigauge(app.UIFigure, 'circular');
            app.PowerkWGauge.Limits = [0 60];
            app.PumpspeedLabel = uilabel(app.UIFigure);
            app.PumpspeedLabel.Text = 'Pump speed [-]';
            app.PumpspeedSpinner = uispinner(app.UIFigure);
            app.PumpspeedSpinner.Step = 0.01;
            app.PumpspeedSpinner.Limits = [0.35 1];
            app.PumpspeedSpinner.Value = 0.853;
            app.CombustiontemperatureEditFieldLabel = uilabel(app.UIFigure);
            app.CombustiontemperatureEditFieldLabel.Text = 'Combustion temperature';
            app.CombustionTEditField = uieditfield(app.UIFigure, 'numeric');
            app.CombustionTEditField.Editable = 'off';
            app.CombustionLamp = uilamp(app.UIFigure);
            app.SteammassflowkghGaugeLabel = uilabel(app.UIFigure);
            app.SteammassflowkghGaugeLabel.Text = 'Steam mass flow [kg/h]';
            app.SteammassflowGauge = uigauge(app.UIFigure, 'linear');
            app.SteammassflowGauge.Limits = [100 500];
            app.SteamTLamp = uilamp(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'System efficiency [%]')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.NlowLabel = uilabel(app.UIFigure);
            app.NlowLabel.Text = 'Nlow [-]';
            app.NlowSpinner = uispinner(app.UIFigure);
            app.NlowSpinner.Step = 0.01;
            app.NlowSpinner.Limits = [0 0.99];
            app.NlowSpinner.Editable = 'off';
            app.NlowSpinner.Value = 0.853;
            app.NhighLabel = uilabel(app.UIFigure);
            app.NhighLabel.Text = 'Nhigh [-]';
            app.NhighSpinner = uispinner(app.UIFigure);
            app.NhighSpinner.Step = 0.01;
            app.NhighSpinner.Limits = [0.1 1];
            app.NhighSpinner.Editable = 'off';
            app.NhighSpinner.Value = 1;
            app.CompressorloadLabel_2 = uilabel(app.UIFigure);
            app.CompressorloadLabel_2.Text = 'Compressor load [%]';
            app.CompressorLowSpinner = uispinner(app.UIFigure);
            app.CompressorLowSpinner.Step = 0.1;
            app.CompressorLowSpinner.Limits = [0 100];
            app.CompressorLowSpinner.Editable = 'off';
            app.CompressorLowSpinner.Value = 85.64;
            app.CompressorloadLabel_3 = uilabel(app.UIFigure);
            app.CompressorloadLabel_3.Text = 'Compressor load [%]';
            app.CompressorHighSpinner = uispinner(app.UIFigure);
            app.CompressorHighSpinner.Step = 0.1;
            app.CompressorHighSpinner.Limits = [0 100];
            app.CompressorHighSpinner.Editable = 'off';
            app.CompressorHighSpinner.Value = 100;
            app.CompressorloadLabel = uilabel(app.UIFigure);
            app.CompressorloadLabel.Text = 'Compressor load [%]';
            app.CompressorloadGauge = uigauge(app.UIFigure, 'circular');
            app.EvaporatorfluegasLabel = uilabel(app.UIFigure);
            app.EvaporatorfluegasLabel.Text = 'Evaporator flue gas [%]';
            app.EvapFrSpinner = uispinner(app.UIFigure);
            app.EvapFrSpinner.Step = 0.1;
            app.EvapFrSpinner.Limits = [0.1 99];
            app.EvapFrSpinner.Editable = 'off';
            app.EvapFrSpinner.Value = 95.16;
            app.VaporfractionLabel = uilabel(app.UIFigure);
            app.VaporfractionLabel.Text = 'Vapor fraction';
            app.x2Gauge = uigauge(app.UIFigure, 'ninetydegree');
            app.x2Gauge.Limits = [85 100];
            app.SaturatedLamp = uilamp(app.UIFigure);
            app.DepthmSpinnerLabel = uilabel(app.UIFigure);
            app.DepthmSpinnerLabel.Text = 'Depth [m]';
            app.DepthSpinner = uispinner(app.UIFigure);
            app.DepthSpinner.Step = 0.1;
            app.DepthSpinner.Limits = [5 150];
            app.DepthSpinner.Editable = 'off';
            app.DepthSpinner.Value = 50;
            app.SuperheatererosionLampLabel = uilabel(app.UIFigure);
            app.SuperheatererosionLampLabel.Text = 'Superheater erosion';
            app.SuperonLamp = uilamp(app.UIFigure);
            app.EvaporatorerosionLampLabel = uilabel(app.UIFigure);
            app.EvaporatorerosionLampLabel.Text = 'Evaporator erosion';
            app.EvaperoLamp = uilamp(app.UIFigure);
            app.MalfunctionLabel = uilabel(app.UIFigure);
            app.MalfunctionLabel.Text = 'Malfunction';
            app.DeadLamp = uilamp(app.UIFigure);
            app.FuelconsumptionlhEditFieldLabel = uilabel(app.UIFigure);
            app.FuelconsumptionlhEditFieldLabel.Text = 'Fuel consumption [l/h]';
            app.FuelconsumEditField = uieditfield(app.UIFigure, 'numeric');
            app.FuelconsumEditField.Editable = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Submarine trajectory')
            xlabel(app.UIAxes2, 'Distance [m]')
            ylabel(app.UIAxes2, 'Depth [m]')
            app.UIAxes2.YTick = [-150 -125 -100 -75 -50 -25 0 25];
            app.UIAxes2.YTickLabel = {'-150'; '-125'; '-100'; '-75'; '-50'; '-25'; '0'; '25'};
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Horizontal velocity')
            xlabel(app.UIAxes3, 'Time [s]')
            ylabel(app.UIAxes3, 'v_x [m/s]')
            app.UIAxes3.YTick = [-6 -4 -2 0 2 4 6];
            app.UIAxes3.YTickLabel = {'-6'; '-4'; '-2'; '0'; '2'; '4'; '6'};
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Vertical velocity')
            xlabel(app.UIAxes4, 'Time [s]')
            ylabel(app.UIAxes4, 'v_y [m/s]')
            app.UIAxes4.YTick = [-6 -4 -2 0 2 4 6];
            app.UIAxes4.YTickLabel = {'-6'; '-4'; '-2'; '0'; '2'; '4'; '6'};
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.Text = 'Start';
            app.ForwardButton = uibutton(app.UIFigure, 'state');
            app.ForwardButton.Text = 'Forward';
            app.BalastpumpreferenceSliderLabel = uilabel(app.UIFigure);
            app.BalastpumpreferenceSliderLabel.Text = '[Balast pump reference %]';
            app.BalastpumpreferenceSlider = uislider(app.UIFigure);
            app.BalastpumpreferenceSlider.Value = 50;
            app.AngledegSpinnerLabel = uilabel(app.UIFigure);
            app.AngledegSpinnerLabel.Text = 'Angle [deg]';
            app.AngledegSpinner = uispinner(app.UIFigure);
            app.AngledegSpinner.Limits = [-7 7];
            app.BalastpumpGaugeLabel = uilabel(app.UIFigure);
            app.BalastpumpGaugeLabel.Text = 'Balast pump [%]';
            app.BalastpumpGauge = uigauge(app.UIFigure, 'linear');
            app.StopButton = uibutton(app.UIFigure, 'state');
            app.StopButton.Text = 'Stop';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SubmarineGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end